classdef Echidna < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EchidnaCCEPViewerUIFigure      matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        SavecurrentconfigMenu          matlab.ui.container.Menu
        LoadconfigMenu                 matlab.ui.container.Menu
        SettingsMenu                   matlab.ui.container.Menu
        ChangeoutputdirectoryMenu      matlab.ui.container.Menu
        SwitchtoMenu                   matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        ADDENTIREELECTRODEPanel        matlab.ui.container.Panel
        sEEGlabelEditFieldLabel        matlab.ui.control.Label
        EntiresEEGlabelEditField       matlab.ui.control.EditField
        JackboxstartEditFieldLabel     matlab.ui.control.Label
        JackboxstartEditField          matlab.ui.control.NumericEditField
        NumberofcontactsSpinnerLabel   matlab.ui.control.Label
        NumberofcontactsSpinner        matlab.ui.control.Spinner
        EntireAddButton                matlab.ui.control.Button
        ADDINDIVIDUALCONTACTPanel      matlab.ui.container.Panel
        sEEGlabelEditFieldLabel_2      matlab.ui.control.Label
        IndsEEGlabelEditField          matlab.ui.control.EditField
        JackboxEditFieldLabel          matlab.ui.control.Label
        JackboxEditField               matlab.ui.control.NumericEditField
        IndAddButton                   matlab.ui.control.Button
        ContactEditFieldLabel          matlab.ui.control.Label
        ContactEditField               matlab.ui.control.NumericEditField
        CCEPDURATIONPanel              matlab.ui.container.Panel
        PremsEditFieldLabel            matlab.ui.control.Label
        PremsEditField                 matlab.ui.control.NumericEditField
        PostmsEditFieldLabel           matlab.ui.control.Label
        PostmsEditField                matlab.ui.control.NumericEditField
        RemoveselectedButton           matlab.ui.control.Button
        RecordButton                   matlab.ui.control.StateButton
        FilterlinenoiseCheckBox        matlab.ui.control.CheckBox
        RecordPSButton                 matlab.ui.control.StateButton
        CloseallfiguresButton          matlab.ui.control.Button
        ConnectedtoNetComLampLabel     matlab.ui.control.Label
        ConnectedtoNetComLamp          matlab.ui.control.Lamp
        RecordingLampLabel             matlab.ui.control.Label
        RecordingLamp                  matlab.ui.control.Lamp
        CONTACTSTOVIEWListBoxLabel     matlab.ui.control.Label
        CONTACTSTOVIEWListBox          matlab.ui.control.ListBox
        NeuralynxPCIPv4EditFieldLabel  matlab.ui.control.Label
        NeuralynxPCIPv4EditField       matlab.ui.control.EditField
        IOcurveTab                     matlab.ui.container.Tab
        IOCurveButton                  matlab.ui.control.StateButton
        IOTable                        matlab.ui.control.Table
        AddpointPanel                  matlab.ui.container.Panel
        StimulationintensityEditFieldLabel  matlab.ui.control.Label
        StimulationintensityEditField  matlab.ui.control.NumericEditField
        RecordIOButton                 matlab.ui.control.StateButton
        NumpulsesEditFieldLabel        matlab.ui.control.Label
        NumpulsesEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        serverName = '0.0.0.0'; %Ethernet IPv4 address on Neuralnyx PC
        rootdir=fileparts(which(mfilename('fullpath')));
        NetComPath='\NetCom';
        outputPath='\CCEPs';
        sampPerRec=512; %Pegasus returns 512 samples per record
        depthlist=[];
        numback=3;
        pre_post_dur=[200 500];
        CSCs=[]; CSCLabels={};
        isIOrecording=false;
        isPSRecording=false;
        isRecording=false;
        isFiltered=false;
    end

    methods (Access = public)
          function nc_connect(app)
            if NlxAreWeConnected() %If we somehow get here while already connected...
                app.ConnectedtoNetComLamp.Color=[0 1 0]; 
                app.NeuralynxPCIPv4EditField.Enable='off'; drawnow(); return; 
            end       
            app.NeuralynxPCIPv4EditField.Enable='off'; app.EntireAddButton.Enable='off'; app.IndAddButton.Enable='off';drawnow();
            fprintf(['Connecting to server (' app.serverName ')...']);
            succeeded = NlxConnectToServer(app.serverName);
            if succeeded ~= 1, fprintf('FAILED to connect to NetCom. \n Verify that the IPv4 address on the Pegasus PC matches the one listed.\n'); 
                app.NeuralynxPCIPv4EditField.Enable='on';app.EntireAddButton.Enable='on'; app.IndAddButton.Enable='on'; app.RecordButton.Enable='off'; app.RecordPSButton.Enable='off'; app.RecordIOButton.Enable='off';
            else
                fprintf('Connected.\n'); app.ConnectedtoNetComLamp.Color=[0 1 0]; 
                NlxSetApplicationName('CCEP viewer'); %Id the program to the server
                app.NeuralynxPCIPv4EditField.Enable='off';app.EntireAddButton.Enable='on'; app.IndAddButton.Enable='on';app.RecordButton.Enable='on'; app.RecordPSButton.Enable='on'; app.RecordIOButton.Enable='on';
            end
            drawnow();
          end
          
        function recordPS(app)
            freqs=logspace(log10(2),log10(32),33); numCycles=6; %Morlet params
            inputranges=[]; srs=[];
            [~, pegObjs, ~]=NlxGetDASObjectsAndTypes(); %list of all Pegasus acq entities (and the acq system)
            depthLabs={};
            for idx=1:length(app.CSCs)
                currch=['CSC' num2str(app.CSCs(idx))];
                depthLabs{end+1}=app.CSCLabels{idx}(isletter(app.CSCLabels{idx}));
                if isempty(find(strcmp(pegObjs,currch),1)), fprintf('FAILED to load %s. Ending recording.', currch); app.isRecording=0; break; end
                NlxOpenStream(currch);
                [~,tmp]=NlxSendCommand(['-GetInputRange ' currch]); inputranges(idx)=str2double(cell2mat(tmp));
                [~,tmp]=NlxSendCommand(['-GetSampleFrequency ' currch]); srs(idx)=str2double(cell2mat(tmp));
            end
            
            depths=unique(depthLabs);app.depthlist=depths; 
            for d=1:length(depths)
                tmp=depths{d};
                depthcounts(d)=length(find(strcmp(depthLabs,tmp)));
            end
            
            for d=1:length(depths)
                depthfig(d)=figure('Visible','off','Name', 'Echidna CCEP Viewer', 'NumberTitle', 'off'); 
                depthsubplots{d}= tight_subplot(depthcounts(d),2,[0.03 0.01], [0.05, 0.1], [.06 .01]);
                depthsgt{d}=sgtitle(sprintf('Depth %s. %d CCEPs plotted', depths{d}, 0));
            end     
            clear depthLabs;
            
            allDataArrays={}; for ch=1:length(app.CSCs), allDataArrays{ch}=[]; end
            notchfilt=designfilt('bandstopiir', 'FilterOrder',2, 'HalfPowerFrequency1',58, 'HalfPowerFrequency2',62, 'DesignMethod','butter','SampleRate', 1000);
            while(app.isPSRecording)
               try
                    pause(1); %let data load up in the buffers
                    for ch=1:length(app.CSCs)
                        [~, dataArray, ~, ~, ~, ~, ~]= NlxGetNewCSCData(['CSC' num2str(app.CSCs(ch))]);
                        allDataArrays{ch}=[allDataArrays{ch} dataArray];
                    end
                catch
                    fprintf('Something went wrong. Try restarting the recording, or try restarting Echidna. \n'); app.isRecording=false;
                end
            end
            
            for ch=1:length(app.CSCs), wavcoeff{ch}=[]; morpower{ch}=[]; corrpower{ch}=[]; ispeaks{ch}=[]; end
            meancorrpow=[]; stdcorrpow=[];
            
            t=-2:1/1000:2;
            for ch=1:length(app.CSCs)
                allDataArrays{ch}=(inputranges(ch)/32767)*downsample(allDataArrays{ch},srs(ch)/1000);
                for f=1:length(freqs)
                    wav=sl_cmorwav(freqs(f),t,numCycles);
                    wavcoeff{ch}(f,:)=conv(allDataArrays{ch},wav, 'same');
                    morpower{ch}(f)=mean(abs(wavcoeff{ch}(f,:)),2); %complex mag
                end
                [b,~]=robustfit(log(freqs), log(morpower{ch})); %robust reg in log-log space
                fit=exp(b(1)+b(2)*log(freqs));
                tmp=morpower{ch}-fit; tmp=tmp./max(tmp); corrpower{ch}=tmp
                stdcorrpow(ch)=std(corrpower{ch});
                meancorrpow(ch)=mean(corrpower{ch});
                for f=1:length(freqs)
                    if corrpower{ch}(f) >= (meancorrpow(ch)+stdcorrpow(ch)), ispeaks{ch}(f)=1;
                    else, ispeaks{ch}(f)=0;
                    end
                end
            end
                        
            for d=1:length(depths), set(depthfig(d),'Visible','on'); end; depthctr=ones(1,length(depths));
            
            currmax=1; currmin=1e8;
            for ch=1:length(app.CSCs) %yaxis scaling
                    tmp=max(abs(morpower{ch})); if tmp>currmax, currmax=tmp; end
                    tmp=min(abs(morpower{ch})); if tmp<currmin, currmin=tmp; end
            end
            
            for ch=1:length(app.CSCs)
                currdepth=find(strcmp(depths,app.CSCLabels{ch}(isletter(app.CSCLabels{ch}))));
                depthsgt{currdepth}.String=sprintf('Depth %s power spectra', depths{currdepth});

                currax=depthsubplots{currdepth}(2*(depthctr(currdepth)-1) + 1); axes(currax);
                loglog(freqs,morpower{ch});
                set(currax,'Xlim',[2 35]);
                set(currax,'XTick',[2:4:32]);
                set(get(currax,'YLabel'), 'String', app.CSCLabels{ch});
                set(get(currax,'YLabel'), 'Units', 'Normalized', 'Position', [-.02 .5 0]);
                set(currax, 'Ylim', [currmin currmax]);
                set(currax,'YTick', currmax); set(currax,'YTickLabels', round(currmax));
                if depthctr(currdepth)<depthcounts(currdepth), set(currax,'XTickLabels',{});
                else, set(currax, 'XTickLabels', strsplit(num2str([2:4:32])));set(get(currax,'XLabel'), 'String', 'Frequency (Hz)');
                end
                
                currax=depthsubplots{currdepth}(2*(depthctr(currdepth)-1) + 2);axes(currax);
                semilogx(freqs,corrpower{ch});
                set(currax, 'Ylim', [-.5 1.25]);
                set(currax,'Xlim',[2 35]);
                set(currax,'XTick', [2:4:32]);
                
                set(currax,'YTick',[-.4:.2:1]); set(currax,'YTickLabels',{}); hold on; yline(0);
                idx=find(ispeaks{ch}==1); scatter(freqs(idx), 1.1*ones(1,length(idx)), 20, 'r*')
                if depthctr(currdepth)<depthcounts(currdepth), set(currax,'XTickLabels',{});
                else, set(currax, 'XTickLabels', strsplit(num2str([2:4:32]))); set(get(currax,'XLabel'), 'String', 'Frequency (Hz)');
                end
                
                depthctr(currdepth)=depthctr(currdepth)+1;
            end
            
            for idx=1:length(app.CSCs)
                currch=['CSC' num2str(app.CSCs(idx))];
                try NlxCloseStream(currch);
                catch
                end
            end
        end
        
        
          function recordCCEPs(app)
              inputranges=[]; srs=[];
            [~, pegObjs, ~]=NlxGetDASObjectsAndTypes(); %list of all Pegasus acq entities (and the acq system)
            depthLabs={};
            for idx=1:length(app.CSCs)
                currch=['CSC' num2str(app.CSCs(idx))];
                depthLabs{end+1}=app.CSCLabels{idx}(isletter(app.CSCLabels{idx}));
                if isempty(find(strcmp(pegObjs,currch),1)), fprintf('FAILED to load %s. Ending recording.', currch); app.isRecording=0; break; end
                NlxOpenStream(currch);
                [~,tmp]=NlxSendCommand(['-GetInputRange ' currch]); inputranges(idx)=str2double(cell2mat(tmp));
                [~,tmp]=NlxSendCommand(['-GetSampleFrequency ' currch]); srs(idx)=str2double(cell2mat(tmp));
            end
            NlxOpenStream('Events');
            depths=unique(depthLabs); app.depthlist=depths;
            for d=1:length(depths)
                tmp=depths{d};
                depthcounts(d)=length(find(strcmp(depthLabs,tmp)));
            end
            
            allCCEPs={};
            totalCCEPDur=abs(app.pre_post_dur(1))+abs(app.pre_post_dur(2))+1;
            lastArrays={};  currArrays={}; timestampArrays={}; counter=0;
            for ch=1:length(app.CSCs), allCCEPs{ch}={}; end
            prevEvtoCheck=[];
            
            for d=1:length(depths)
                depthfig(d)=figure('Visible','off','Name', 'Echidna CCEP Viewer', 'NumberTitle', 'off'); 
                depthsubplots{d}= tight_subplot(depthcounts(d),1,[0.03 0.01], [0.05, 0.1], [.06 .01]);
                depthsgt{d}=sgtitle(sprintf('Depth %s. %d CCEPs plotted', depths{d}, 0));
            end
            for d=1:length(depths)
                set(depthfig(d),'Visible','on');
            end
            clear depthLabs;
             
            notchfilt=designfilt('bandstopiir', 'FilterOrder',2, 'HalfPowerFrequency1',58, 'HalfPowerFrequency2',62, 'DesignMethod','butter','SampleRate', 1000);
            while(app.isRecording)         
                try
                pause(1); %let data load up in the buffers
                [~, evTS, ~, ttlvals, ~, ~, ~] = NlxGetNewEventData('Events');
                ttlvals
                evTS(find(ttlvals==0))=[]; ttlvals(find(ttlvals==0))=[];
                counter=counter+length(evTS);
                for ch=1:length(app.CSCs)
                    [~, dataArray, timestamps, ~, ~, ~, ~]= NlxGetNewCSCData(['CSC' num2str(app.CSCs(ch))]);
                    currArrays{ch}=dataArray;
                    timestampArrays{ch}=timestamps;
                end
                
                %Update preliminary CCEPs
                if ~isempty(prevEvtoCheck)
                    for e=1:length(prevEvtoCheck)
                        previdx=prevEvtoCheck(e);
                        for ch=1:length(app.CSCs)
                            reqlength=postSamps+preSamps+1-length(allCCEPs{ch}{end-previdx});
                            allCCEPs{ch}{end-previdx} = downsample([allCCEPs{ch}{end-previdx}, currArrays{ch}(1:reqlength)],srs(ch)/1000);
                        end
                    end
                end
                
                prevEvtoCheck=[];
                
                evIdx=[];
                for ch=1:length(app.CSCs) %need to do this for the individual channels, because they get their records pulled asynchronously
                    preSamps=abs(ceil(app.pre_post_dur(1)*srs(ch)/1000));
                    postSamps=abs(ceil(app.pre_post_dur(2)*srs(ch)/1000));
                    for e=1:length(evTS)
                        secondsin=double(evTS(e)-timestampArrays{ch}(1))*1e-6; evIdx(e)=ceil(secondsin*srs(ch));
                        if evIdx(e) < preSamps %if need to go to previous buffered array...
                            if isempty(lastArrays) %if there isn't a last array
                            else
                                reqSamps=preSamps-evIdx(e); %num samples needed from last array
                                allCCEPs{ch}{end+1}= downsample([lastArrays{ch}((end-reqSamps):end) currArrays{ch}(1:preSamps+postSamps-reqSamps)],srs(ch)/1000);
                            end
                        elseif evIdx(e)+postSamps > length(currArrays{ch}) %need to look at these next time
                            allCCEPs{ch}{end+1}= currArrays{ch}(evIdx(e)-preSamps:end); %preliminary (non-downsampled)
                            prevEvtoCheck(end+1)=length(evTS)-e; %Ie, how many events back should we look next pass?
                        else %the stars have aligned and the whole thing is in the current buffer 
                            allCCEPs{ch}{end+1}= downsample(currArrays{ch}((evIdx(e)-preSamps):(evIdx(e)+postSamps)),srs(ch)/1000);
                        end
                    end
                end
                prevEvtoCheck=unique(prevEvtoCheck);
                lastArrays=currArrays;
                depthctr=ones(1,length(depths));
                
                
                currmax=1;
                for ch=1:length(app.CSCs) %yaxis scaling
                    if ~isempty(allCCEPs{ch}(1:end-app.numback))
                        tmp=(inputranges(ch)/32767)*cellmean(app,allCCEPs{ch}(1:end-app.numback)); tmp=max(abs(tmp(app.pre_post_dur(1)+100:app.pre_post_dur(1)+500)));
                    if tmp>currmax, currmax=tmp; end 
                    end
                end
                
                for ch=1:length(app.CSCs)
                    currdepth=find(strcmp(depths,app.CSCLabels{ch}(isletter(app.CSCLabels{ch}))));
                    currax=depthsubplots{currdepth}(depthctr(currdepth));
                    axes(currax);
                    
                    depthsgt{currdepth}.String=sprintf('Depth %s. %d CCEPs plotted', depths{currdepth}, length(allCCEPs{ch}(1:end-app.numback)));
                    if ~isempty(allCCEPs{ch}(1:end-app.numback))
                        if app.isFiltered, plot((-1*app.pre_post_dur(1)):app.pre_post_dur(2),filtfilt(notchfilt,(inputranges(ch)/32767)*cellmean(app,allCCEPs{ch}(1:end-app.numback))));
                        else, plot((-1*app.pre_post_dur(1)):app.pre_post_dur(2),(inputranges(ch)/32767)*cellmean(app,allCCEPs{ch}(1:end-app.numback)));
                        end
                        xline(0);
                        set(currax, 'Ylim', [-1.5*currmax 1.5*currmax]); 
                        set(get(currax,'YLabel'), 'String', app.CSCLabels{ch}); 
                        set(get(currax,'YLabel'), 'Units', 'Normalized', 'Position', [-.02 .5 0]);
                        set(currax, 'YTick', currmax*1.5); 
                        set(currax, 'YTickLabels', {sprintf('%d',round(currmax*1.5))});
                        set(currax,'Xlim',[-1*app.pre_post_dur(1), app.pre_post_dur(2)]);
                        if depthctr(currdepth)<depthcounts(currdepth)
                            set(currax,'XTickLabels',{});
                        end
                    end
                    depthctr(currdepth)=depthctr(currdepth)+1;
                    if depthctr(currdepth) > depthcounts(currdepth)
                        set(get(currax,'XLabel'), 'String', 'time(ms)');
                    end
                end
                catch
                    fprintf('Something went wrong. Try restarting the recording, or try restarting Echidna. \n'); app.isRecording=false;
               end
                 %records have timestamp difference of 16000 microseconds = .016s = record size (512)/sample rate (32000)
            end
            for idx=1:length(app.CSCs)
                currch=['CSC' num2str(app.CSCs(idx))];
                try NlxCloseStream(currch);
                catch 
                end
            end
          end
          
        function updateElecs(app)
            checkConnection(app);
            itemlist={};
            [app.CSCs, ord]=sort(app.CSCs);
            app.CSCLabels=app.CSCLabels(ord);
            for i=1:length(app.CSCs), itemlist{i}=[app.CSCLabels{i}, '   (CSC', num2str(app.CSCs(i)),')']; end
            
            set(app.CONTACTSTOVIEWListBox, 'Items', itemlist);
            set(app.IOTable, 'ColumnName', {'Stim. intensity', itemlist{:}});
            drawnow();
            if ~isempty(itemlist), app.RecordButton.Enable='on'; app.RecordPSButton.Enable='on';
            else, app.RecordButton.Enable='off'; app.RecordPSButton.Enable='off';
            end
        end
    end
    
    methods (Access = private)
         function cmean=cellmean(app, cells)
            sum=double(zeros(size(cells{1})));
            for i=1:length(cells)
                sum=sum+double(cells{i});
            end
            cmean=sum./length(cells);
            bl=mean(cmean(1:app.pre_post_dur(1))); cmean=cmean-bl;
         end
         
        function cmean=cellmean_nobl(app,cells)
            sum=double(zeros(size(cells{1})));
            for i=1:length(cels)
                sum=sum+double(cells{i});
            end
            cmean=sum./length(cells);
        end
        
        function isgood = checkConnection(app)
             if ~NlxAreWeConnected()
                 isgood=0;
                if isequal(app.ConnectedtoNetComLamp.Color, [0 1 0])
                        app.RecordingLamp.Color=[0.5 0.5 0.5]; 
                        app.isRecording=0;
                        app.RecordButton.Enable='off'; app.RecordPSButton.Enable='off';
                        app.ConnectedtoNetComLamp.Color=[0.5 0.5 0.5];
                        fprintf('Disconnected from NetCom. Attempting automatic reconnect.')
                        nc_connect(app);
                end
             else
                 isgood=1;
             end
        end
        
        function setico(app,fig)
            figNameOrig=fig.Name;
            figNameTemp=[fig.Name, tempname('$')];
            fig.Name=figNameTemp;
            drawnow;
            ww=matlab.internal.webwindowmanager.instance.findAllWebwindows();
            webWindow=matlab.internal.webwindow.empty();
            for ii=1:numel(ww)
                if strcmp(ww(ii).Title, figNameTemp)
                    webWindow=ww(ii);
                    break;
                end
            end
            fig.Name=figNameOrig;
            webWindow.Icon=fullfile(app.rootdir,'EchidnaLogo.ico');
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           set(app.EchidnaCCEPViewerUIFigure, 'Visible','off');
           set(app.NeuralynxPCIPv4EditField, 'Value', app.serverName);
            app.EntireAddButton.Parent = app.ADDENTIREELECTRODEPanel;
            app.NumberofcontactsSpinner.Parent = app.ADDENTIREELECTRODEPanel;
            app.EntiresEEGlabelEditField.Parent= app.ADDENTIREELECTRODEPanel;
            app.JackboxstartEditField.Parent = app.ADDENTIREELECTRODEPanel;
            
            setico(app,app.EchidnaCCEPViewerUIFigure);
            set(app.EchidnaCCEPViewerUIFigure, 'Visible','on');

            fprintf('\n\n--**--**--CCEP VIEWER--**--**--\n');
            fprintf('Launching GUI.\n'); 
            warning('off', 'all');
            p=genpath([app.rootdir app.NetComPath]); addpath(p);
            nc_connect(app);
        end

        % Close request function: EchidnaCCEPViewerUIFigure
        function EchidnaCCEPViewerUIFigureCloseRequest(app, event)
            close all;
            delete(app)
            for i=1:length(app.CSCs)
                try currch=['CSC' num2str(app.CSCs(idx))]; NlxCloseStream(currch);
                catch
                end
            end   
            try NlxDisconnectFromServer();
            catch
            end
            %exit()
        end

        % Button pushed function: EntireAddButton
        function EntireAddButtonPushed(app, event)
            stereoName=app.EntiresEEGlabelEditField.Value;
            stereoNum=app.NumberofcontactsSpinner.Value;
            jbnum=app.JackboxstartEditField.Value;
            if isempty(stereoName) || isempty(stereoNum) || isempty(jbnum)
                return;
            end
            
            tmp= app.CSCs; tmpLab=app.CSCLabels;
            for i=1:stereoNum
                if ismember([stereoName num2str(i)], app.CSCLabels) || ismember(jbnum+i-1, app.CSCs)
                    fprintf('Attempted to add electrode or CSC already in list. Selection not added.\n'); return
                else
                    tmp(end+1)= jbnum+i-1;
                    tmpLab{end+1}=[stereoName num2str(i)];
                end
            end
            app.CSCLabels=tmpLab; app.CSCs=tmp;
            updateElecs(app);
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
           value = app.RecordButton.Value;
             if value== 1 %record
                 close all;
                app.EntireAddButton.Enable = 'off'; app.NumberofcontactsSpinner.Enable='off'; app.JackboxstartEditField.Enable='off'; app.EntiresEEGlabelEditField.Enable='off';
                app.IndAddButton.Enable='off'; app.ContactEditField.Enable='off'; app.JackboxEditField.Enable='off'; app.IndsEEGlabelEditField.Enable='off';
                app.PremsEditField.Enable='off'; app.PostmsEditField.Enable='off';
                app.RemoveselectedButton.Enable='off'; app.FilterlinenoiseCheckBox.Enable='off'; app.RecordPSButton.Enable='off'; app.CloseallfiguresButton.Enable='off';
                app.RecordingLamp.Color=[164 52 235]./255;
                drawnow();
                
                app.isRecording=true;
                if checkConnection(app)
                    recordCCEPs(app);
                else
                end
            else %stop record
                app.isRecording=false;

                app.RecordingLamp.Color=[.5 .5 .5];
                app.EntireAddButton.Enable = 'on'; app.NumberofcontactsSpinner.Enable='on'; app.JackboxstartEditField.Enable='on'; app.EntiresEEGlabelEditField.Enable='on';
                app.IndAddButton.Enable='on'; app.ContactEditField.Enable='on'; app.JackboxEditField.Enable='on';app.IndsEEGlabelEditField.Enable='on';
                app.PremsEditField.Enable='on'; app.PostmsEditField.Enable='on'; app.FilterlinenoiseCheckBox.Enable='on';
                app.RemoveselectedButton.Enable='on'; app.RecordPSButton.Enable='on';
                drawnow();
                %save figures
                fh=findobj('Type', 'Figure'); outdir=[app.outputPath '\' datestr(now, 'ddmmmyyyy_HH-MM-SS')];
                mkdir(outdir); cd(outdir);
                for i=1:length(fh)
                    crdepth=strsplit(fh(i).Children(end).String); crdepth=crdepth{2};
                    saveas(fh(i), [crdepth '_CCEPs.tif']);
                end
                cd(app.rootdir);
                app.CloseallfiguresButton.Enable='on';
             end
        end

        % Button pushed function: IndAddButton
        function IndAddButtonPushed(app, event)
            stereoName=app.IndsEEGlabelEditField.Value;
            stereoNum=app.ContactEditField.Value;
            jbnum=app.JackboxEditField.Value;
            if ismember([stereoName num2str(stereoNum)], app.CSCLabels) || ismember(jbnum, app.CSCs) 
                fprintf('Attempted to add electrode or CSC already in list. Selection not added.');
            else
                app.CSCs(end+1)=jbnum;
                app.CSCLabels{end+1}=[stereoName num2str(stereoNum)];
                updateElecs(app);
            end
        end

        % Button pushed function: RemoveselectedButton
        function RemoveselectedButtonPushed(app, event)
            [~,idx]=ismember(app.CONTACTSTOVIEWListBox.Value, app.CONTACTSTOVIEWListBox.Items);
            app.CONTACTSTOVIEWListBox.Items(idx)=[];
            app.CSCs(idx)=[];
            app.CSCLabels(idx)=[];     
            updateElecs(app);    
        end

        % Value changed function: PremsEditField
        function PremsEditFieldValueChanged(app, event)
            value = app.PremsEditField.Value;
            app.pre_post_dur(1)=value;
        end

        % Value changed function: PostmsEditField
        function PostmsEditFieldValueChanged(app, event)
            value = app.PostmsEditField.Value;
            app.pre_post_dur(2)=value;
        end

        % Value changed function: NeuralynxPCIPv4EditField
        function NeuralynxPCIPv4EditFieldValueChanged(app, event)
            value = app.NeuralynxPCIPv4EditField.Value;
            app.serverName=value;
            nc_connect(app);
        end

        % Value changed function: EntiresEEGlabelEditField
        function EntiresEEGlabelEditFieldValueChanged(app, event)
            value = app.EntiresEEGlabelEditField.Value;
            app.EntiresEEGlabelEditField.Value=upper(value(find(~isspace(value))));
            if any(~isletter(value))
                app.EntiresEEGlabelEditField.Value='';
                fprintf('sEEG labels can only contain characters A-Z.\n')
            end
        end

        % Value changed function: IndsEEGlabelEditField
        function IndsEEGlabelEditFieldValueChanged(app, event)
            value = app.EntiresEEGlabelEditField.Value;
            app.EntiresEEGlabelEditField.Value=upper(value(find(~isspace(value))));
            if any(~isletter(value))
                app.EntiresEEGlabelEditField.Value='';
                fprintf('sEEG labels can only contain characters A-Z.\n')
            end
        end

        % Value changed function: FilterlinenoiseCheckBox
        function FilterlinenoiseCheckBoxValueChanged(app, event)
            value = app.FilterlinenoiseCheckBox.Value;
            if value== 1
                app.isFiltered=1; fprintf('Filtering');
            else 
                app.isFiltered=0;
            end
        end

        % Value changed function: RecordPSButton
        function RecordPSButtonValueChanged(app, event)
            value = app.RecordPSButton.Value;
             if value== 1 %record
                app.EntireAddButton.Enable = 'off'; app.NumberofcontactsSpinner.Enable='off'; app.JackboxstartEditField.Enable='off'; app.EntiresEEGlabelEditField.Enable='off';
                app.IndAddButton.Enable='off'; app.ContactEditField.Enable='off'; app.JackboxEditField.Enable='off'; app.IndsEEGlabelEditField.Enable='off';
                app.PremsEditField.Enable='off'; app.PostmsEditField.Enable='off';
                app.RemoveselectedButton.Enable='off'; app.FilterlinenoiseCheckBox.Enable='off'; app.RecordButton.Enable='off';
                app.RecordingLamp.Color=[164 52 235]./255;
                drawnow();
                
                app.isPSRecording=true;
                if checkConnection(app)
                    recordPS(app);
                else
                end
            
            else %stop record
                app.isPSRecording=false;

                app.RecordingLamp.Color=[.5 .5 .5];
                app.EntireAddButton.Enable = 'on'; app.NumberofcontactsSpinner.Enable='on'; app.JackboxstartEditField.Enable='on'; app.EntiresEEGlabelEditField.Enable='on';
                app.IndAddButton.Enable='on'; app.ContactEditField.Enable='on'; app.JackboxEditField.Enable='on';app.IndsEEGlabelEditField.Enable='on';
                app.PremsEditField.Enable='on'; app.PostmsEditField.Enable='on'; app.FilterlinenoiseCheckBox.Enable='on';
                app.RemoveselectedButton.Enable='on'; app.RecordButton.Enable='on';
                drawnow();
             end
        end

        % Menu selected function: SavecurrentconfigMenu
        function SavecurrentconfigMenuSelected(app, event)
            [fn,path]= uiputfile('*.mat');
            sn=app.serverName; rd=app.rootdir; ncp=app.NetComPath; spr=app.sampPerRec; ppd=app.pre_post_dur; cscs=app.CSCs; csclabs=app.CSCLabels; isf=app.isFiltered; opd=app.outputPath;
            save([path fn], 'sn','rd','ncp', 'spr', 'ppd', 'cscs', 'csclabs', 'isf', 'opd');
        end

        % Menu selected function: LoadconfigMenu
        function LoadconfigMenuSelected(app, event)
            uiopen('*.mat');
            app.serverName=sn; app.rootdir=rd; app.NetComPath=ncp; app.sampPerRec=spr; app.pre_post_dur=ppd; 
            app.CSCs=cscs; app.CSCLabels=csclabs; app.isFiltered=isf; app.outputPath=opd;
            updateElecs(app);
            app.PremsEditField.Value=app.pre_post_dur(1);
            app.PostmsEditField.Value=app.pre_post_dur(2);
            app.NeuralynxPCIPv4EditField.Value=app.serverName;
            nc_connect(app);
        end

        % Menu selected function: ChangeoutputdirectoryMenu
        function ChangeoutputdirectoryMenuSelected(app, event)
            selpath=uigetdir(app.outputPath);
            app.outputPath=selpath;
        end

        % Button pushed function: CloseallfiguresButton
        function CloseallfiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: IOCurveButton
        function IOCurveButtonValueChanged(app, event)
            value = app.IOCurveButton.Value;
            if value == 1 %record
                app.EntireAddButton.Enable = 'off'; app.NumberofcontactsSpinner.Enable='off'; app.JackboxstartEditField.Enable='off'; app.EntiresEEGlabelEditField.Enable='off';
                app.IndAddButton.Enable='off'; app.ContactEditField.Enable='off'; app.JackboxEditField.Enable='off'; app.IndsEEGlabelEditField.Enable='off';
                app.PremsEditField.Enable='off'; app.PostmsEditField.Enable='off';
                app.RemoveselectedButton.Enable='off'; app.FilterlinenoiseCheckBox.Enable='off'; app.RecordButton.Enable='off';
                app.RecordingLamp.Color=[164 52 235]./255;
                drawnow();
                
                 app.isPSRecording=true;
                if checkConnection(app)
                    recordPS(app);
                else
                end
            else %stop record
                app.RecordingLamp.Color=[.5 .5 .5];
                app.EntireAddButton.Enable = 'on'; app.NumberofcontactsSpinner.Enable='on'; app.JackboxstartEditField.Enable='on'; app.EntiresEEGlabelEditField.Enable='on';
                app.IndAddButton.Enable='on'; app.ContactEditField.Enable='on'; app.JackboxEditField.Enable='on';app.IndsEEGlabelEditField.Enable='on';
                app.PremsEditField.Enable='on'; app.PostmsEditField.Enable='on'; app.FilterlinenoiseCheckBox.Enable='on';
                app.RemoveselectedButton.Enable='on'; app.RecordButton.Enable='on';
                drawnow();
            end
        end

        % Callback function
        function IOCurveButtonValueChanged2(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EchidnaCCEPViewerUIFigure = uifigure;
            app.EchidnaCCEPViewerUIFigure.Name = 'Echidna';
            app.EchidnaCCEPViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @EchidnaCCEPViewerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.EchidnaCCEPViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.SavecurrentconfigMenu = uimenu(app.FileMenu);
            app.SavecurrentconfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SavecurrentconfigMenuSelected, true);
            app.SavecurrentconfigMenu.Text = 'Save current config';
            app.LoadconfigMenu = uimenu(app.FileMenu);
            app.LoadconfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadconfigMenuSelected, true);
            app.LoadconfigMenu.Text = 'Load config';
            app.SettingsMenu = uimenu(app.EchidnaCCEPViewerUIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ChangeoutputdirectoryMenu = uimenu(app.SettingsMenu);
            app.ChangeoutputdirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeoutputdirectoryMenuSelected, true);
            app.ChangeoutputdirectoryMenu.Text = 'Change output directory';
            app.SwitchtoMenu = uimenu(app.EchidnaCCEPViewerUIFigure);
            app.SwitchtoMenu.Enable = 'off';
            app.SwitchtoMenu.Text = 'Switch to...';
            app.TabGroup = uitabgroup(app.EchidnaCCEPViewerUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ADDENTIREELECTRODEPanel = uipanel(app.MainTab);
            app.ADDENTIREELECTRODEPanel.Title = 'ADD ENTIRE ELECTRODE';
            app.sEEGlabelEditFieldLabel = uilabel(app.ADDENTIREELECTRODEPanel);
            app.sEEGlabelEditFieldLabel.Text = 'sEEG label';
            app.EntiresEEGlabelEditField = uieditfield(app.ADDENTIREELECTRODEPanel, 'text');
            app.EntiresEEGlabelEditField.ValueChangedFcn = createCallbackFcn(app, @EntiresEEGlabelEditFieldValueChanged, true);
            app.EntiresEEGlabelEditField.Value = 'A';
            app.JackboxstartEditFieldLabel = uilabel(app.ADDENTIREELECTRODEPanel);
            app.JackboxstartEditFieldLabel.Text = 'CSC start #';
            app.JackboxstartEditField = uieditfield(app.ADDENTIREELECTRODEPanel, 'numeric');
            app.JackboxstartEditField.Limits = [1 256];
            app.JackboxstartEditField.RoundFractionalValues = 'on';
            app.JackboxstartEditField.ValueDisplayFormat = '%d';
            app.JackboxstartEditField.Value = 1;
            app.NumberofcontactsSpinnerLabel = uilabel(app.ADDENTIREELECTRODEPanel);
            app.NumberofcontactsSpinnerLabel.Text = '# of contacts';
            app.NumberofcontactsSpinner = uispinner(app.ADDENTIREELECTRODEPanel);
            app.NumberofcontactsSpinner.Limits = [1 32];
            app.NumberofcontactsSpinner.Value = 1;
            app.EntireAddButton = uibutton(app.ADDENTIREELECTRODEPanel, 'push');
            app.EntireAddButton.ButtonPushedFcn = createCallbackFcn(app, @EntireAddButtonPushed, true);
            app.EntireAddButton.Text = 'Add';
            app.ADDINDIVIDUALCONTACTPanel = uipanel(app.MainTab);
            app.ADDINDIVIDUALCONTACTPanel.Title = 'ADD INDIVIDUAL CONTACT';
            app.sEEGlabelEditFieldLabel_2 = uilabel(app.ADDINDIVIDUALCONTACTPanel);
            app.sEEGlabelEditFieldLabel_2.Text = 'sEEG label';
            app.IndsEEGlabelEditField = uieditfield(app.ADDINDIVIDUALCONTACTPanel, 'text');
            app.IndsEEGlabelEditField.ValueChangedFcn = createCallbackFcn(app, @IndsEEGlabelEditFieldValueChanged, true);
            app.IndsEEGlabelEditField.Value = 'A';
            app.JackboxEditFieldLabel = uilabel(app.ADDINDIVIDUALCONTACTPanel);
            app.JackboxEditFieldLabel.Text = 'CSC #';
            app.JackboxEditField = uieditfield(app.ADDINDIVIDUALCONTACTPanel, 'numeric');
            app.JackboxEditField.Limits = [1 256];
            app.JackboxEditField.RoundFractionalValues = 'on';
            app.JackboxEditField.ValueDisplayFormat = '%d';
            app.JackboxEditField.Value = 1;
            app.IndAddButton = uibutton(app.ADDINDIVIDUALCONTACTPanel, 'push');
            app.IndAddButton.ButtonPushedFcn = createCallbackFcn(app, @IndAddButtonPushed, true);
            app.IndAddButton.Text = 'Add';
            app.ContactEditFieldLabel = uilabel(app.ADDINDIVIDUALCONTACTPanel);
            app.ContactEditFieldLabel.Text = 'Contact #';
            app.ContactEditField = uieditfield(app.ADDINDIVIDUALCONTACTPanel, 'numeric');
            app.ContactEditField.Limits = [1 32];
            app.ContactEditField.RoundFractionalValues = 'on';
            app.ContactEditField.ValueDisplayFormat = '%d';
            app.ContactEditField.Value = 1;
            app.CCEPDURATIONPanel = uipanel(app.MainTab);
            app.CCEPDURATIONPanel.Title = 'CCEP DURATION';
            app.PremsEditFieldLabel = uilabel(app.CCEPDURATIONPanel);
            app.PremsEditFieldLabel.Text = 'Pre- (ms)';
            app.PremsEditField = uieditfield(app.CCEPDURATIONPanel, 'numeric');
            app.PremsEditField.Limits = [50 1000];
            app.PremsEditField.ValueChangedFcn = createCallbackFcn(app, @PremsEditFieldValueChanged, true);
            app.PremsEditField.Value = 200;
            app.PostmsEditFieldLabel = uilabel(app.CCEPDURATIONPanel);
            app.PostmsEditFieldLabel.Text = 'Post- (ms)';
            app.PostmsEditField = uieditfield(app.CCEPDURATIONPanel, 'numeric');
            app.PostmsEditField.Limits = [50 1000];
            app.PostmsEditField.ValueChangedFcn = createCallbackFcn(app, @PostmsEditFieldValueChanged, true);
            app.PostmsEditField.Value = 500;
            app.RemoveselectedButton = uibutton(app.MainTab, 'push');
            app.RemoveselectedButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveselectedButtonPushed, true);
            app.RemoveselectedButton.Text = 'Remove selected';
            app.RecordButton = uibutton(app.MainTab, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Enable = 'off';
            app.RecordButton.Text = {'Record CCEPs'; ''};
            app.FilterlinenoiseCheckBox = uicheckbox(app.MainTab);
            app.FilterlinenoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterlinenoiseCheckBoxValueChanged, true);
            app.FilterlinenoiseCheckBox.Text = 'Filter line noise';
            app.RecordPSButton = uibutton(app.MainTab, 'state');
            app.RecordPSButton.ValueChangedFcn = createCallbackFcn(app, @RecordPSButtonValueChanged, true);
            app.RecordPSButton.Enable = 'off';
            app.RecordPSButton.Text = 'Record power spectra';
            app.CloseallfiguresButton = uibutton(app.MainTab, 'push');
            app.CloseallfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallfiguresButtonPushed, true);
            app.CloseallfiguresButton.Text = 'Close all figures';
            app.ConnectedtoNetComLampLabel = uilabel(app.MainTab);
            app.ConnectedtoNetComLampLabel.Text = 'Connected to NetCom';
            app.ConnectedtoNetComLamp = uilamp(app.MainTab);
            app.RecordingLampLabel = uilabel(app.MainTab);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.MainTab);
            app.CONTACTSTOVIEWListBoxLabel = uilabel(app.MainTab);
            app.CONTACTSTOVIEWListBoxLabel.Text = 'CONTACTS TO VIEW';
            app.CONTACTSTOVIEWListBox = uilistbox(app.MainTab);
            app.CONTACTSTOVIEWListBox.Items = {};
            app.CONTACTSTOVIEWListBox.Multiselect = 'on';
            app.CONTACTSTOVIEWListBox.Value = {};
            app.NeuralynxPCIPv4EditFieldLabel = uilabel(app.MainTab);
            app.NeuralynxPCIPv4EditFieldLabel.Text = 'Neuralynx PC IPv4';
            app.NeuralynxPCIPv4EditField = uieditfield(app.MainTab, 'text');
            app.NeuralynxPCIPv4EditField.ValueChangedFcn = createCallbackFcn(app, @NeuralynxPCIPv4EditFieldValueChanged, true);
            app.NeuralynxPCIPv4EditField.Value = '0.0.0.0';
            app.IOcurveTab = uitab(app.TabGroup);
            app.IOcurveTab.Title = 'I/O curve';
            app.IOCurveButton = uibutton(app.IOcurveTab, 'state');
            app.IOCurveButton.ValueChangedFcn = createCallbackFcn(app, @IOCurveButtonValueChanged, true);
            app.IOCurveButton.Enable = 'off';
            app.IOCurveButton.Text = 'Plot I/O curves';
            app.IOTable = uitable(app.IOcurveTab);
            app.IOTable.ColumnName = {'Stim. intensity'};
            app.IOTable.RowName = {};
            app.IOTable.ColumnEditable = false;
            app.AddpointPanel = uipanel(app.IOcurveTab);
            app.AddpointPanel.Title = ' Add point';
            app.StimulationintensityEditFieldLabel = uilabel(app.AddpointPanel);
            app.StimulationintensityEditFieldLabel.Text = 'Stimulation intensity';
            app.StimulationintensityEditField = uieditfield(app.AddpointPanel, 'numeric');
            app.StimulationintensityEditField.Limits = [0 1000000];
            app.StimulationintensityEditField.Tooltip = {'Stimulation'};
            app.StimulationintensityEditField.Value = 50;
            app.RecordIOButton = uibutton(app.AddpointPanel, 'state');
            app.RecordIOButton.Enable = 'off';
            app.RecordIOButton.Text = 'Record';
            app.NumpulsesEditFieldLabel = uilabel(app.AddpointPanel);
            app.NumpulsesEditFieldLabel.Tooltip = {'Number of stimulation pulses to record before generating the average EP.'};
            app.NumpulsesEditFieldLabel.Text = 'Num. pulses';
            app.NumpulsesEditField = uieditfield(app.AddpointPanel, 'numeric');
            app.NumpulsesEditField.Limits = [0 1000000];
            app.NumpulsesEditField.Value = 20;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Echidna

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EchidnaCCEPViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EchidnaCCEPViewerUIFigure)
        end
    end
end